classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SudokuSolverUIFigure   matlab.ui.Figure
        R99                    matlab.ui.control.EditField
        R98                    matlab.ui.control.EditField
        R97                    matlab.ui.control.EditField
        R96                    matlab.ui.control.EditField
        R95                    matlab.ui.control.EditField
        R94                    matlab.ui.control.EditField
        R93                    matlab.ui.control.EditField
        R92                    matlab.ui.control.EditField
        R91                    matlab.ui.control.EditField
        R89                    matlab.ui.control.EditField
        R88                    matlab.ui.control.EditField
        R87                    matlab.ui.control.EditField
        R86                    matlab.ui.control.EditField
        R85                    matlab.ui.control.EditField
        R84                    matlab.ui.control.EditField
        R83                    matlab.ui.control.EditField
        R82                    matlab.ui.control.EditField
        R81                    matlab.ui.control.EditField
        R79                    matlab.ui.control.EditField
        R78                    matlab.ui.control.EditField
        R77                    matlab.ui.control.EditField
        R76                    matlab.ui.control.EditField
        R75                    matlab.ui.control.EditField
        R74                    matlab.ui.control.EditField
        R73                    matlab.ui.control.EditField
        R72                    matlab.ui.control.EditField
        R71                    matlab.ui.control.EditField
        R69                    matlab.ui.control.EditField
        R68                    matlab.ui.control.EditField
        R67                    matlab.ui.control.EditField
        R66                    matlab.ui.control.EditField
        R65                    matlab.ui.control.EditField
        R64                    matlab.ui.control.EditField
        R63                    matlab.ui.control.EditField
        R62                    matlab.ui.control.EditField
        R61                    matlab.ui.control.EditField
        R59                    matlab.ui.control.EditField
        R58                    matlab.ui.control.EditField
        R57                    matlab.ui.control.EditField
        R56                    matlab.ui.control.EditField
        R55                    matlab.ui.control.EditField
        R54                    matlab.ui.control.EditField
        R53                    matlab.ui.control.EditField
        R52                    matlab.ui.control.EditField
        R51                    matlab.ui.control.EditField
        R49                    matlab.ui.control.EditField
        R48                    matlab.ui.control.EditField
        R47                    matlab.ui.control.EditField
        R46                    matlab.ui.control.EditField
        R45                    matlab.ui.control.EditField
        R44                    matlab.ui.control.EditField
        R43                    matlab.ui.control.EditField
        R42                    matlab.ui.control.EditField
        R41                    matlab.ui.control.EditField
        R39                    matlab.ui.control.EditField
        R38                    matlab.ui.control.EditField
        R37                    matlab.ui.control.EditField
        R36                    matlab.ui.control.EditField
        R35                    matlab.ui.control.EditField
        R34                    matlab.ui.control.EditField
        R33                    matlab.ui.control.EditField
        R32                    matlab.ui.control.EditField
        R31                    matlab.ui.control.EditField
        R29                    matlab.ui.control.EditField
        R28                    matlab.ui.control.EditField
        R27                    matlab.ui.control.EditField
        R26                    matlab.ui.control.EditField
        R25                    matlab.ui.control.EditField
        R24                    matlab.ui.control.EditField
        R23                    matlab.ui.control.EditField
        R22                    matlab.ui.control.EditField
        R21                    matlab.ui.control.EditField
        R19                    matlab.ui.control.EditField
        R18                    matlab.ui.control.EditField
        R17                    matlab.ui.control.EditField
        R16                    matlab.ui.control.EditField
        R15                    matlab.ui.control.EditField
        R14                    matlab.ui.control.EditField
        R13                    matlab.ui.control.EditField
        R12                    matlab.ui.control.EditField
        R11                    matlab.ui.control.EditField
        Switch                 matlab.ui.control.Switch
        SolveButton            matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        Button                 matlab.ui.control.Button
        InsertYourPuzzleLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        count = 0; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Switch.Value = " ";
            
            app.R11.InputType = "alphanumeric";
            app.R11.FontSize = 24;
            app.R11.HorizontalAlignment = "center";
            app.R12.InputType = "alphanumeric";
            app.R12.FontSize = 24;
            app.R12.HorizontalAlignment = "center";
            app.R13.InputType = "alphanumeric";
            app.R13.FontSize = 24;
            app.R13.HorizontalAlignment = "center";
            app.R14.InputType = "alphanumeric";
            app.R14.FontSize = 24;
            app.R14.HorizontalAlignment = "center";
            app.R15.InputType = "alphanumeric";
            app.R15.FontSize = 24;
            app.R15.HorizontalAlignment = "center";
            app.R16.InputType = "alphanumeric";
            app.R16.FontSize = 24;
            app.R16.HorizontalAlignment = "center";
            app.R17.InputType = "alphanumeric";
            app.R17.FontSize = 24;
            app.R17.HorizontalAlignment = "center";
            app.R18.InputType = "alphanumeric";
            app.R18.FontSize = 24;
            app.R18.HorizontalAlignment = "center";
            app.R19.InputType = "alphanumeric";
            app.R19.FontSize = 24;
            app.R19.HorizontalAlignment = "center";

            app.R21.InputType = "alphanumeric";
            app.R21.FontSize = 24;
            app.R21.HorizontalAlignment = "center";
            app.R22.InputType = "alphanumeric";
            app.R22.FontSize = 24;
            app.R22.HorizontalAlignment = "center";
            app.R23.InputType = "alphanumeric";
            app.R23.FontSize = 24;
            app.R23.HorizontalAlignment = "center";
            app.R24.InputType = "alphanumeric";
            app.R24.FontSize = 24;
            app.R24.HorizontalAlignment = "center";
            app.R25.InputType = "alphanumeric";
            app.R25.FontSize = 24;
            app.R25.HorizontalAlignment = "center";
            app.R26.InputType = "alphanumeric";
            app.R26.FontSize = 24;
            app.R26.HorizontalAlignment = "center";
            app.R27.InputType = "alphanumeric";
            app.R27.FontSize = 24;
            app.R27.HorizontalAlignment = "center";
            app.R28.InputType = "alphanumeric";
            app.R28.FontSize = 24;
            app.R28.HorizontalAlignment = "center";
            app.R29.InputType = "alphanumeric";
            app.R29.FontSize = 24;
            app.R29.HorizontalAlignment = "center";

            app.R31.InputType = "alphanumeric";
            app.R31.FontSize = 24;
            app.R31.HorizontalAlignment = "center";
            app.R32.InputType = "alphanumeric";
            app.R32.FontSize = 24;
            app.R32.HorizontalAlignment = "center";
            app.R33.InputType = "alphanumeric";
            app.R33.FontSize = 24;
            app.R33.HorizontalAlignment = "center";
            app.R34.InputType = "alphanumeric";
            app.R34.FontSize = 24;
            app.R34.HorizontalAlignment = "center";
            app.R35.InputType = "alphanumeric";
            app.R35.FontSize = 24;
            app.R35.HorizontalAlignment = "center";
            app.R36.InputType = "alphanumeric";
            app.R36.FontSize = 24;
            app.R36.HorizontalAlignment = "center";
            app.R37.InputType = "alphanumeric";
            app.R37.FontSize = 24;
            app.R37.HorizontalAlignment = "center";
            app.R38.InputType = "alphanumeric";
            app.R38.FontSize = 24;
            app.R38.HorizontalAlignment = "center";
            app.R39.InputType = "alphanumeric";
            app.R39.FontSize = 24;
            app.R39.HorizontalAlignment = "center";

            app.R41.InputType = "alphanumeric";
            app.R41.FontSize = 24;
            app.R41.HorizontalAlignment = "center";
            app.R42.InputType = "alphanumeric";
            app.R42.FontSize = 24;
            app.R42.HorizontalAlignment = "center";
            app.R43.InputType = "alphanumeric";
            app.R43.FontSize = 24;
            app.R43.HorizontalAlignment = "center";
            app.R44.InputType = "alphanumeric";
            app.R44.FontSize = 24;
            app.R44.HorizontalAlignment = "center";
            app.R45.InputType = "alphanumeric";
            app.R45.FontSize = 24;
            app.R45.HorizontalAlignment = "center";
            app.R46.InputType = "alphanumeric";
            app.R46.FontSize = 24;
            app.R46.HorizontalAlignment = "center";
            app.R47.InputType = "alphanumeric";
            app.R47.FontSize = 24;
            app.R47.HorizontalAlignment = "center";
            app.R48.InputType = "alphanumeric";
            app.R48.FontSize = 24;
            app.R48.HorizontalAlignment = "center";
            app.R49.InputType = "alphanumeric";
            app.R49.FontSize = 24;
            app.R49.HorizontalAlignment = "center";

            app.R51.InputType = "alphanumeric";
            app.R51.FontSize = 24;
            app.R51.HorizontalAlignment = "center";
            app.R52.InputType = "alphanumeric";
            app.R52.FontSize = 24;
            app.R52.HorizontalAlignment = "center";
            app.R53.InputType = "alphanumeric";
            app.R53.FontSize = 24;
            app.R53.HorizontalAlignment = "center";
            app.R54.InputType = "alphanumeric";
            app.R54.FontSize = 24;
            app.R54.HorizontalAlignment = "center";
            app.R55.InputType = "alphanumeric";
            app.R55.FontSize = 24;
            app.R55.HorizontalAlignment = "center";
            app.R56.InputType = "alphanumeric";
            app.R56.FontSize = 24;
            app.R56.HorizontalAlignment = "center";
            app.R57.InputType = "alphanumeric";
            app.R57.FontSize = 24;
            app.R57.HorizontalAlignment = "center";
            app.R58.InputType = "alphanumeric";
            app.R58.FontSize = 24;
            app.R58.HorizontalAlignment = "center";
            app.R59.InputType = "alphanumeric";
            app.R59.FontSize = 24;
            app.R59.HorizontalAlignment = "center";

            app.R61.InputType = "alphanumeric";
            app.R61.FontSize = 24;
            app.R61.HorizontalAlignment = "center";
            app.R62.InputType = "alphanumeric";
            app.R62.FontSize = 24;
            app.R62.HorizontalAlignment = "center";
            app.R63.InputType = "alphanumeric";
            app.R63.FontSize = 24;
            app.R63.HorizontalAlignment = "center";
            app.R64.InputType = "alphanumeric";
            app.R64.FontSize = 24;
            app.R64.HorizontalAlignment = "center";
            app.R65.InputType = "alphanumeric";
            app.R65.FontSize = 24;
            app.R65.HorizontalAlignment = "center";
            app.R66.InputType = "alphanumeric";
            app.R66.FontSize = 24;
            app.R66.HorizontalAlignment = "center";
            app.R67.InputType = "alphanumeric";
            app.R67.FontSize = 24;
            app.R67.HorizontalAlignment = "center";
            app.R68.InputType = "alphanumeric";
            app.R68.FontSize = 24;
            app.R68.HorizontalAlignment = "center";
            app.R69.InputType = "alphanumeric";
            app.R69.FontSize = 24;
            app.R69.HorizontalAlignment = "center";

            app.R71.InputType = "alphanumeric";
            app.R71.FontSize = 24;
            app.R71.HorizontalAlignment = "center";
            app.R72.InputType = "alphanumeric";
            app.R72.FontSize = 24;
            app.R72.HorizontalAlignment = "center";
            app.R73.InputType = "alphanumeric";
            app.R73.FontSize = 24;
            app.R73.HorizontalAlignment = "center";
            app.R74.InputType = "alphanumeric";
            app.R74.FontSize = 24;
            app.R74.HorizontalAlignment = "center";
            app.R75.InputType = "alphanumeric";
            app.R75.FontSize = 24;
            app.R75.HorizontalAlignment = "center";
            app.R76.InputType = "alphanumeric";
            app.R76.FontSize = 24;
            app.R76.HorizontalAlignment = "center";
            app.R77.InputType = "alphanumeric";
            app.R77.FontSize = 24;
            app.R77.HorizontalAlignment = "center";
            app.R78.InputType = "alphanumeric";
            app.R78.FontSize = 24;
            app.R78.HorizontalAlignment = "center";
            app.R79.InputType = "alphanumeric";
            app.R79.FontSize = 24;
            app.R79.HorizontalAlignment = "center";

            app.R81.InputType = "alphanumeric";
            app.R81.FontSize = 24;
            app.R81.HorizontalAlignment = "center";
            app.R82.InputType = "alphanumeric";
            app.R82.FontSize = 24;
            app.R82.HorizontalAlignment = "center";
            app.R83.InputType = "alphanumeric";
            app.R83.FontSize = 24;
            app.R83.HorizontalAlignment = "center";
            app.R84.InputType = "alphanumeric";
            app.R84.FontSize = 24;
            app.R84.HorizontalAlignment = "center";
            app.R85.InputType = "alphanumeric";
            app.R85.FontSize = 24;
            app.R85.HorizontalAlignment = "center";
            app.R86.InputType = "alphanumeric";
            app.R86.FontSize = 24;
            app.R86.HorizontalAlignment = "center";
            app.R87.InputType = "alphanumeric";
            app.R87.FontSize = 24;
            app.R87.HorizontalAlignment = "center";
            app.R88.InputType = "alphanumeric";
            app.R88.FontSize = 24;
            app.R88.HorizontalAlignment = "center";
            app.R89.InputType = "alphanumeric";
            app.R89.FontSize = 24;
            app.R89.HorizontalAlignment = "center";

            app.R91.InputType = "alphanumeric";
            app.R91.FontSize = 24;
            app.R91.HorizontalAlignment = "center";
            app.R92.InputType = "alphanumeric";
            app.R92.FontSize = 24;
            app.R92.HorizontalAlignment = "center";
            app.R93.InputType = "alphanumeric";
            app.R93.FontSize = 24;
            app.R93.HorizontalAlignment = "center";
            app.R94.InputType = "alphanumeric";
            app.R94.FontSize = 24;
            app.R94.HorizontalAlignment = "center";
            app.R95.InputType = "alphanumeric";
            app.R95.FontSize = 24;
            app.R95.HorizontalAlignment = "center";
            app.R96.InputType = "alphanumeric";
            app.R96.FontSize = 24;
            app.R96.HorizontalAlignment = "center";
            app.R97.InputType = "alphanumeric";
            app.R97.FontSize = 24;
            app.R97.HorizontalAlignment = "center";
            app.R98.InputType = "alphanumeric";
            app.R98.FontSize = 24;
            app.R98.HorizontalAlignment = "center";
            app.R99.InputType = "alphanumeric";
            app.R99.FontSize = 24;
            app.R99.HorizontalAlignment = "center";


        end

        % Callback function: Button, Switch
        function ButtonPushed(app, event)
            if event.EventName == "ButtonPushed"
                message = "Insert the Sudoku puzzle you would like solved. " + newline + newline + ...
                    "You can either click each individual square you would like to fill in, " + ...
                    "or you can start at the top left square and tab through them all. " + ...
                    "When you type a number, it will auto-tab for you to speed up and simplify the process." + newline + newline + ...
                    "Any numbers you don't know just leave blank." + newline + newline + ...
                    "There are a few advanced puzzles this solver can't solve, so if you " + ...
                    "have an error pop up, you will need to solve the puzzle by hand or wait until the next release to try again." + newline + newline  + ...
                    "Also, the AutoTab button does exactly that. When on, typing numbers automatically pushes you to " + ...
                    "the next number, but if you want to hit the tab button yourself, you can by turning AutoTab off.";
                
                uialert(app.SudokuSolverUIFigure, message, "Help", "Icon", "info");

            % Gotta know whether they hit the button or the switch:
            elseif (event.EventName == "ValueChanged")

                % This is unnecessary except I wanted it to be obvious that Items may be weird.
                app.count = app.count + 1;
                if mod(app.count, 2) == 1
                    app.Switch.Value =  " Autotab   ";      % Look at that whitespace, it has to be like that.
                else
                    app.Switch.Value = " ";
                end
            end
        end

        % Value changing function: R11
        function R11ValueChanging(app, event)
            changingValue = event.Value;
            while (changingValue ~= "" && changingValue ~= "1" && changingValue ~= "2" && changingValue ~= "3" && changingValue ~= "4" ...
                    && changingValue ~= "5" && changingValue ~= "6" && changingValue ~= "7" && changingValue ~= "8" && changingValue ~= "9")
                app.R11.Value = "";
                changingValue = "";
            end
            if app.Switch.Value == " "
                    

            end
        end

        % Value changing function: R12
        function R12ValueChanging(app, event)
            changingValue = event.Value;
            while (changingValue ~= "" && changingValue ~= "1" && changingValue ~= "2" && changingValue ~= "3" && changingValue ~= "4" ...
                    && changingValue ~= "5" && changingValue ~= "6" && changingValue ~= "7" && changingValue ~= "8" && changingValue ~= "9")
                app.R12.Value = "";
                changingValue = "";
            end
            if app.Switch.Value == " "
                    

            end
        end

        % Value changing function: R13
        function R13ValueChanging(app, event)
            changingValue = event.Value;
            while (changingValue ~= "" && changingValue ~= "1" && changingValue ~= "2" && changingValue ~= "3" && changingValue ~= "4" ...
                    && changingValue ~= "5" && changingValue ~= "6" && changingValue ~= "7" && changingValue ~= "8" && changingValue ~= "9")
                app.R13.Value = "";
                changingValue = "";
            end
            if app.Switch.Value == " "
                    

            end
        end

        % Value changing function: R14
        function R14ValueChanging(app, event)
            changingValue = event.Value;
            while (changingValue ~= "" && changingValue ~= "1" && changingValue ~= "2" && changingValue ~= "3" && changingValue ~= "4" ...
                    && changingValue ~= "5" && changingValue ~= "6" && changingValue ~= "7" && changingValue ~= "8" && changingValue ~= "9")
                app.R14.Value = "";
                changingValue = "";
            end
            if app.Switch.Value == " "
                    

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SudokuSolverUIFigure = uifigure('Visible', 'off');
            app.SudokuSolverUIFigure.Name = 'Sudoku Solver';
            app.SudokuSolverUIFigure.Icon = fullfile(pathToMLAPP, 'Resources', 'Cuneiform_sumer_dingir.svg.png');
            app.InsertYourPuzzleLabel = uilabel(app.SudokuSolverUIFigure);
            app.InsertYourPuzzleLabel.Text = 'Insert Your Puzzle:';
            app.Button = uibutton(app.SudokuSolverUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tag = 'HelpButton';
            app.Button.Icon = fullfile(pathToMLAPP, 'Resources', 'Blue_question_mark_icon.svg.png');
            app.Button.Text = '';
            app.Image = uiimage(app.SudokuSolverUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'blank-sudoku-grid.png');
            app.SolveButton = uibutton(app.SudokuSolverUIFigure, 'push');
            app.SolveButton.Text = 'Solve!';
            app.Switch = uiswitch(app.SudokuSolverUIFigure, 'slider');
            app.Switch.Items = {' Autotab   ', ' '};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Switch.Tag = 'AutoTab';
            app.Switch.Value = ' ';
            app.R11 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R11.InputType = 'digits';
            app.R11.ValueChangedFcn = createCallbackFcn(app, @R11ValueChanged, true);
            app.R11.ValueChangingFcn = createCallbackFcn(app, @R11ValueChanging, true);
            app.R12 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R12.InputType = 'digits';
            app.R12.ValueChangingFcn = createCallbackFcn(app, @R12ValueChanging, true);
            app.R13 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R13.InputType = 'digits';
            app.R13.ValueChangingFcn = createCallbackFcn(app, @R13ValueChanging, true);
            app.R14 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R14.InputType = 'digits';
            app.R14.ValueChangingFcn = createCallbackFcn(app, @R14ValueChanging, true);
            app.R15 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R15.InputType = 'digits';
            app.R16 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R16.InputType = 'digits';
            app.R17 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R17.InputType = 'digits';
            app.R18 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R18.InputType = 'digits';
            app.R19 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R19.InputType = 'digits';
            app.R21 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R21.InputType = 'digits';
            app.R22 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R22.InputType = 'digits';
            app.R23 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R23.InputType = 'digits';
            app.R24 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R24.InputType = 'digits';
            app.R25 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R25.InputType = 'digits';
            app.R26 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R26.InputType = 'digits';
            app.R27 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R27.InputType = 'digits';
            app.R28 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R28.InputType = 'digits';
            app.R29 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R29.InputType = 'digits';
            app.R31 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R31.InputType = 'digits';
            app.R32 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R32.InputType = 'digits';
            app.R33 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R33.InputType = 'digits';
            app.R34 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R34.InputType = 'digits';
            app.R35 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R35.InputType = 'digits';
            app.R36 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R36.InputType = 'digits';
            app.R37 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R37.InputType = 'digits';
            app.R38 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R38.InputType = 'digits';
            app.R39 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R39.InputType = 'digits';
            app.R41 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R41.InputType = 'digits';
            app.R42 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R42.InputType = 'digits';
            app.R43 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R43.InputType = 'digits';
            app.R44 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R44.InputType = 'digits';
            app.R45 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R45.InputType = 'digits';
            app.R46 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R46.InputType = 'digits';
            app.R47 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R47.InputType = 'digits';
            app.R48 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R48.InputType = 'digits';
            app.R49 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R49.InputType = 'digits';
            app.R51 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R51.InputType = 'digits';
            app.R52 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R52.InputType = 'digits';
            app.R53 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R53.InputType = 'digits';
            app.R54 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R54.InputType = 'digits';
            app.R55 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R55.InputType = 'digits';
            app.R56 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R56.InputType = 'digits';
            app.R57 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R57.InputType = 'digits';
            app.R58 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R58.InputType = 'digits';
            app.R59 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R59.InputType = 'digits';
            app.R61 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R61.InputType = 'digits';
            app.R62 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R62.InputType = 'digits';
            app.R63 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R63.InputType = 'digits';
            app.R64 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R64.InputType = 'digits';
            app.R65 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R65.InputType = 'digits';
            app.R66 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R66.InputType = 'digits';
            app.R67 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R67.InputType = 'digits';
            app.R68 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R68.InputType = 'digits';
            app.R69 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R69.InputType = 'digits';
            app.R71 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R71.InputType = 'digits';
            app.R72 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R72.InputType = 'digits';
            app.R73 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R73.InputType = 'digits';
            app.R74 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R74.InputType = 'digits';
            app.R75 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R75.InputType = 'digits';
            app.R76 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R76.InputType = 'digits';
            app.R77 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R77.InputType = 'digits';
            app.R78 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R78.InputType = 'digits';
            app.R79 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R79.InputType = 'digits';
            app.R81 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R81.InputType = 'digits';
            app.R82 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R82.InputType = 'digits';
            app.R83 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R83.InputType = 'digits';
            app.R84 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R84.InputType = 'digits';
            app.R85 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R85.InputType = 'digits';
            app.R86 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R86.InputType = 'digits';
            app.R87 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R87.InputType = 'digits';
            app.R88 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R88.InputType = 'digits';
            app.R89 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R89.InputType = 'digits';
            app.R91 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R91.InputType = 'digits';
            app.R92 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R92.InputType = 'digits';
            app.R93 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R93.InputType = 'digits';
            app.R94 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R94.InputType = 'digits';
            app.R95 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R95.InputType = 'digits';
            app.R96 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R96.InputType = 'digits';
            app.R97 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R97.InputType = 'digits';
            app.R98 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R98.InputType = 'digits';
            app.R99 = uieditfield(app.SudokuSolverUIFigure, 'text');
            app.R99.InputType = 'digits';
            % Show the figure after all components are created
            app.SudokuSolverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SudokuSolverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SudokuSolverUIFigure)
        end
    end
end